classdef equaliser_app < matlab.apps.AppBase
    %EQUALISER_APP - Student Number: C1930510 - Name: Kieran Williams

    % Properties that correspond to app components
    properties (Access = public)
        C1930510EqualizerAppUIFigure  matlab.ui.Figure
        SaveAudioButton               matlab.ui.control.Button
        ResetSlidersButton            matlab.ui.control.Button
        PlayOriginalButton            matlab.ui.control.Button
        SelectPresetButton            matlab.ui.control.Button
        FilterPresetFilePathLabel     matlab.ui.control.Label
        EditField_2                   matlab.ui.control.EditField
        SavePresetButton              matlab.ui.control.Button
        EFXPanel                      matlab.ui.container.Panel
        DampSlider                    matlab.ui.control.Slider
        DampSliderLabel               matlab.ui.control.Label
        WahFreqHzKnob                 matlab.ui.control.DiscreteKnob
        WahFreqHzKnobLabel            matlab.ui.control.Label
        MaxCutoffKHzKnob              matlab.ui.control.DiscreteKnob
        MaxCutoffKHzKnobLabel         matlab.ui.control.Label
        MinCutoffHzKnob               matlab.ui.control.DiscreteKnob
        MinCutoffHzKnobLabel          matlab.ui.control.Label
        WahWahButton                  matlab.ui.control.StateButton
        StopButton                    matlab.ui.control.Button
        PlayPauseButton               matlab.ui.control.Button
        SelectFileButton              matlab.ui.control.Button
        AudioFilePathLabel            matlab.ui.control.Label
        EditField                     matlab.ui.control.EditField
        s10                           matlab.ui.control.Slider
        KHzLabel_4                    matlab.ui.control.Label
        s9                            matlab.ui.control.Slider
        KHzLabel_3                    matlab.ui.control.Label
        s8                            matlab.ui.control.Slider
        KHzLabel_2                    matlab.ui.control.Label
        s7                            matlab.ui.control.Slider
        KHzLabel                      matlab.ui.control.Label
        s6                            matlab.ui.control.Slider
        KHzLabel_5                    matlab.ui.control.Label
        s5                            matlab.ui.control.Slider
        HzLabel_4                     matlab.ui.control.Label
        s4                            matlab.ui.control.Slider
        HzLabel_3                     matlab.ui.control.Label
        s3                            matlab.ui.control.Slider
        HzLabel_2                     matlab.ui.control.Label
        s2                            matlab.ui.control.Slider
        HzLabel                       matlab.ui.control.Label
        s1                            matlab.ui.control.Slider
        HzSliderLabel                 matlab.ui.control.Label
        EQPanel                       matlab.ui.container.Panel
        val10                         matlab.ui.control.NumericEditField
        val9                          matlab.ui.control.NumericEditField
        val8                          matlab.ui.control.NumericEditField
        val7                          matlab.ui.control.NumericEditField
        val6                          matlab.ui.control.NumericEditField
        val5                          matlab.ui.control.NumericEditField
        val4                          matlab.ui.control.NumericEditField
        val3                          matlab.ui.control.NumericEditField
        val2                          matlab.ui.control.NumericEditField
        val1                          matlab.ui.control.NumericEditField
        VolPanel                      matlab.ui.container.Panel
        MasterSlider                  matlab.ui.control.Slider
        MasterSliderLabel             matlab.ui.control.Label
        ControlsPanel                 matlab.ui.container.Panel
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        functions = addpath('functions/');

    end
    
    properties (Access = public)
        orig_y = -1;
        orig_Fs = -1;
        y = -1;
        Fs = -1;
        player = -1; 
        presetMatrix = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            filePath = "./audio_samples/acoustic.wav";
            loadAudio(app, filePath);
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            if (isplaying(app.player))
                stop(app.player);
            end
            resetSliderValues(app);
            try
                [file,path] = uigetfile({'*.wav;*.mp3;*.aac;*.flac;*.m4a;*.aiff;', 'Audio Files'});
                filePath = append(path, file);
                loadAudio(app, filePath);
            catch e
                disp(e);
            end
        end

        % Button pushed function: PlayPauseButton
        function PlayPauseButtonPushed(app, event)
            app.player = audioplayer(app.y, app.Fs);
            if (app.player ~= -1)
                if (isplaying(app.player))
                    pause(app.player);
                else 
                    resume(app.player);
                end
            end   
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if (app.player ~= -1)
                stop(app.player)
            end
        end

        % Value changed function: MasterSlider
        function MasterSliderValueChanged(app, event)
            value = app.MasterSlider.Value;
            calculateFilters(app);
        end

        % Value changed function: s1
        function s1ValueChanged(app, event)
            value = app.s1.Value;
            app.val1.Value = value;
            calculateFilters(app);
        end

        % Value changed function: s2
        function s2ValueChanged(app, event)
            value = app.s2.Value;
            app.val2.Value = value;
            calculateFilters(app);
        end

        % Value changed function: s3
        function s3ValueChanged(app, event)
            value = app.s3.Value;
            app.val3.Value = value;
            calculateFilters(app);
        end

        % Value changed function: s4
        function s4ValueChanged(app, event)
            value = app.s4.Value;
            app.val4.Value = value;
            calculateFilters(app);
        end

        % Value changed function: s5
        function s5ValueChanged(app, event)
            value = app.s5.Value;
            app.val5.Value = value;
            calculateFilters(app);
        end

        % Value changed function: s6
        function s6ValueChanged(app, event)
            value = app.s6.Value;
            app.val6.Value = value;
            calculateFilters(app);
        end

        % Value changed function: s7
        function s7ValueChanged(app, event)
            value = app.s7.Value;
            app.val7.Value = value;
            calculateFilters(app);
        end

        % Value changed function: s8
        function s8ValueChanged(app, event)
            value = app.s8.Value;
            app.val8.Value = value;
            calculateFilters(app);
        end

        % Value changed function: s9
        function s9ValueChanged(app, event)
            value = app.s9.Value;
            app.val9.Value = value;
            calculateFilters(app);
        end

        % Value changed function: s10
        function s10ValueChanged(app, event)
            value = app.s10.Value;
            app.val10.Value = value;
            calculateFilters(app);
        end

        % Callback function
        function ReverbKnobValueChanged(app, event)
            value = app.ReverbKnob.Value;
            calculateFilters(app)
        end

        % Button pushed function: SelectPresetButton
        function SelectPresetButtonPushed(app, event)
            try
                [file,path] = uigetfile({'*.dat;', 'Preset File'});
                filePath = append(path, file);
                loadPreset(app, filePath);
                app.val1.Value = app.s1.Value;
                app.val2.Value = app.s2.Value;
                app.val3.Value = app.s3.Value;
                app.val4.Value = app.s4.Value;
                app.val5.Value = app.s5.Value;
                app.val6.Value = app.s6.Value;
                app.val7.Value = app.s7.Value;
                app.val8.Value = app.s8.Value;
                app.val9.Value = app.s9.Value;
                app.val10.Value = app.s10.Value;
            catch e
                disp(e);
            end
        end

        % Button pushed function: SavePresetButton
        function SavePresetButtonPushed(app, event)
            savePreset(app);
        end

        % Button pushed function: ResetSlidersButton
        function ResetSlidersButtonPushed(app, event)
            resetSliderValues(app);
            app.val1.Value = app.s1.Value;
            app.val2.Value = app.s2.Value;
            app.val3.Value = app.s3.Value;
            app.val4.Value = app.s4.Value;
            app.val5.Value = app.s5.Value;
            app.val6.Value = app.s6.Value;
            app.val7.Value = app.s7.Value;
            app.val8.Value = app.s8.Value;
            app.val9.Value = app.s9.Value;
            app.val10.Value = app.s10.Value;
        end

        % Button pushed function: PlayOriginalButton
        function PlayOriginalButtonPushed(app, event)
            app.player = audioplayer(app.orig_y, app.orig_Fs);
            play(app.player);
        end

        % Value changed function: WahWahButton
        function WahWahButtonValueChanged(app, event)
            value = app.WahWahButton.Value;
            calculateFilters(app);
        end

        % Button pushed function: SaveAudioButton
        function SaveAudioButtonPushed(app, event)
            try
                [file, path] = uiputfile({'*.wav;', 'Wav File: (*.wav)';'*.flac;', 'Flac File: (*.flac)';'*.m4a;', 'M4a File: (*.m4a)';}, 'Save Audio');
                pathWithFileName = strcat(path, file);
                saveAudio(app, pathWithFileName);
            catch e
                disp(e);
            end
        end

        % Value changed function: WahFreqHzKnob
        function WahFreqHzKnobValueChanged(app, event)
            value = app.WahFreqHzKnob.Value;
            calculateFilters(app);
        end

        % Value changed function: MaxCutoffKHzKnob
        function MaxCutoffKHzKnobValueChanged(app, event)
            value = app.MaxCutoffKHzKnob.Value;
            calculateFilters(app);
        end

        % Value changed function: MinCutoffHzKnob
        function MinCutoffHzKnobValueChanged(app, event)
            value = app.MinCutoffHzKnob.Value;
            calculateFilters(app);
        end

        % Value changed function: DampSlider
        function DampSliderValueChanged(app, event)
            value = app.DampSlider.Value;
            calculateFilters(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.C1930510EqualizerAppUIFigure = uifigure('Visible', 'off');
            app.C1930510EqualizerAppUIFigure.Name = 'C1930510 Equalizer App';
            app.UIAxes = uiaxes(app.C1930510EqualizerAppUIFigure);
            title(app.UIAxes, 'Original')
            app.UIAxes.ZTick = [];
            app.UIAxes_2 = uiaxes(app.C1930510EqualizerAppUIFigure);
            title(app.UIAxes_2, 'Filter')
            app.UIAxes_2.ZTick = [];
            app.ControlsPanel = uipanel(app.C1930510EqualizerAppUIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.VolPanel = uipanel(app.C1930510EqualizerAppUIFigure);
            app.VolPanel.Title = 'Vol';
            app.MasterSliderLabel = uilabel(app.VolPanel);
            app.MasterSliderLabel.Text = 'Master';
            app.MasterSlider = uislider(app.VolPanel);
            app.MasterSlider.Limits = [1 100];
            app.MasterSlider.MajorTicks = [0 50 100];
            app.MasterSlider.MajorTickLabels = {'0 ', '50', '100', ''};
            app.MasterSlider.ValueChangedFcn = createCallbackFcn(app, @MasterSliderValueChanged, true);
            app.MasterSlider.MinorTicks = [];
            app.MasterSlider.Value = 50;
            app.EQPanel = uipanel(app.C1930510EqualizerAppUIFigure);
            app.EQPanel.Title = 'EQ';
            app.val1 = uieditfield(app.EQPanel, 'numeric');
            app.val1.Editable = 'off';
            app.val2 = uieditfield(app.EQPanel, 'numeric');
            app.val2.Editable = 'off';
            app.val3 = uieditfield(app.EQPanel, 'numeric');
            app.val3.Editable = 'off';
            app.val4 = uieditfield(app.EQPanel, 'numeric');
            app.val4.Editable = 'off';
            app.val5 = uieditfield(app.EQPanel, 'numeric');
            app.val5.Editable = 'off';
            app.val6 = uieditfield(app.EQPanel, 'numeric');
            app.val6.Editable = 'off';
            app.val7 = uieditfield(app.EQPanel, 'numeric');
            app.val7.Editable = 'off';
            app.val8 = uieditfield(app.EQPanel, 'numeric');
            app.val8.Editable = 'off';
            app.val9 = uieditfield(app.EQPanel, 'numeric');
            app.val9.Editable = 'off';
            app.val10 = uieditfield(app.EQPanel, 'numeric');
            app.val10.Editable = 'off';
            app.HzSliderLabel = uilabel(app.C1930510EqualizerAppUIFigure);
            app.HzSliderLabel.Text = '0-200Hz';
            app.s1 = uislider(app.C1930510EqualizerAppUIFigure);
            app.s1.Limits = [-15 15];
            app.s1.MajorTicks = [-15 0 15];
            app.s1.MajorTickLabels = {'-15', '0', '15', ''};
            app.s1.ValueChangedFcn = createCallbackFcn(app, @s1ValueChanged, true);
            app.s1.MinorTicks = [-15 -13 -11 -9 -7 -5 -3 -1 0 1 3 5 7 9 11 13 15];
            app.HzLabel = uilabel(app.C1930510EqualizerAppUIFigure);
            app.HzLabel.Text = '200-400Hz';
            app.s2 = uislider(app.C1930510EqualizerAppUIFigure);
            app.s2.Limits = [-15 15];
            app.s2.MajorTicks = [-15 0 15];
            app.s2.MajorTickLabels = {'-15', '0', '15', ''};
            app.s2.ValueChangedFcn = createCallbackFcn(app, @s2ValueChanged, true);
            app.s2.MinorTicks = [-15 -13 -11 -9 -7 -5 -3 -1 0 1 3 5 7 9 11 13 15];
            app.HzLabel_2 = uilabel(app.C1930510EqualizerAppUIFigure);
            app.HzLabel_2.Text = '400-800Hz';
            app.s3 = uislider(app.C1930510EqualizerAppUIFigure);
            app.s3.Limits = [-15 15];
            app.s3.MajorTicks = [-15 0 15];
            app.s3.MajorTickLabels = {'-15', '0', '15', ''};
            app.s3.ValueChangedFcn = createCallbackFcn(app, @s3ValueChanged, true);
            app.s3.MinorTicks = [-15 -13 -11 -9 -7 -5 -3 -1 0 1 3 5 7 9 11 13 15];
            app.HzLabel_3 = uilabel(app.C1930510EqualizerAppUIFigure);
            app.HzLabel_3.Text = '800-1200Hz';
            app.s4 = uislider(app.C1930510EqualizerAppUIFigure);
            app.s4.Limits = [-15 15];
            app.s4.MajorTicks = [-15 0 15];
            app.s4.MajorTickLabels = {'-15', '0', '15', ''};
            app.s4.ValueChangedFcn = createCallbackFcn(app, @s4ValueChanged, true);
            app.s4.MinorTicks = [-15 -13 -11 -9 -7 -5 -3 -1 0 1 3 5 7 9 11 13 15];
            app.HzLabel_4 = uilabel(app.C1930510EqualizerAppUIFigure);
            app.HzLabel_4.Text = '1.2-1.8KHz';
            app.s5 = uislider(app.C1930510EqualizerAppUIFigure);
            app.s5.Limits = [-15 15];
            app.s5.MajorTicks = [-15 0 15];
            app.s5.MajorTickLabels = {'-15', '0', '15', ''};
            app.s5.ValueChangedFcn = createCallbackFcn(app, @s5ValueChanged, true);
            app.s5.MinorTicks = [-15 -13 -11 -9 -7 -5 -3 -1 0 1 3 5 7 9 11 13 15];
            app.KHzLabel_5 = uilabel(app.C1930510EqualizerAppUIFigure);
            app.KHzLabel_5.Text = '1.8-3KHz';
            app.s6 = uislider(app.C1930510EqualizerAppUIFigure);
            app.s6.Limits = [-15 15];
            app.s6.MajorTicks = [-15 0 15];
            app.s6.MajorTickLabels = {'-15', '0', '15', ''};
            app.s6.ValueChangedFcn = createCallbackFcn(app, @s6ValueChanged, true);
            app.s6.MinorTicks = [-15 -13 -11 -9 -7 -5 -3 -1 0 1 3 5 7 9 11 13 15];
            app.KHzLabel = uilabel(app.C1930510EqualizerAppUIFigure);
            app.KHzLabel.Text = '3-5KHz';
            app.s7 = uislider(app.C1930510EqualizerAppUIFigure);
            app.s7.Limits = [-15 15];
            app.s7.MajorTicks = [-15 0 15];
            app.s7.MajorTickLabels = {'-15', '0', '15', ''};
            app.s7.ValueChangedFcn = createCallbackFcn(app, @s7ValueChanged, true);
            app.s7.MinorTicks = [-15 -13 -11 -9 -7 -5 -3 -1 0 1 3 5 7 9 11 13 15];
            app.KHzLabel_2 = uilabel(app.C1930510EqualizerAppUIFigure);
            app.KHzLabel_2.Text = '5-7KHz';
            app.s8 = uislider(app.C1930510EqualizerAppUIFigure);
            app.s8.Limits = [-15 15];
            app.s8.MajorTicks = [-15 0 15];
            app.s8.MajorTickLabels = {'-15', '0', '15', ''};
            app.s8.ValueChangedFcn = createCallbackFcn(app, @s8ValueChanged, true);
            app.s8.MinorTicks = [-15 -13 -11 -9 -7 -5 -3 -1 0 1 3 5 7 9 11 13 15];
            app.KHzLabel_3 = uilabel(app.C1930510EqualizerAppUIFigure);
            app.KHzLabel_3.Text = '7-12KHz';
            app.s9 = uislider(app.C1930510EqualizerAppUIFigure);
            app.s9.Limits = [-15 15];
            app.s9.MajorTicks = [-15 0 15];
            app.s9.MajorTickLabels = {'-15', '0', '15', ''};
            app.s9.ValueChangedFcn = createCallbackFcn(app, @s9ValueChanged, true);
            app.s9.MinorTicks = [-15 -13 -11 -9 -7 -5 -3 -1 0 1 3 5 7 9 11 13 15];
            app.KHzLabel_4 = uilabel(app.C1930510EqualizerAppUIFigure);
            app.KHzLabel_4.Text = '>12KHz';
            app.s10 = uislider(app.C1930510EqualizerAppUIFigure);
            app.s10.Limits = [-15 15];
            app.s10.MajorTicks = [-15 0 15];
            app.s10.MajorTickLabels = {'-15', '0', '15', ''};
            app.s10.ValueChangedFcn = createCallbackFcn(app, @s10ValueChanged, true);
            app.s10.MinorTicks = [-15 -13 -11 -9 -7 -5 -3 -1 0 1 3 5 7 9 11 13 15];
            app.EditField = uieditfield(app.C1930510EqualizerAppUIFigure, 'text');
            app.EditField.Editable = 'off';
            app.AudioFilePathLabel = uilabel(app.C1930510EqualizerAppUIFigure);
            app.AudioFilePathLabel.Text = 'Audio File Path: ';
            app.SelectFileButton = uibutton(app.C1930510EqualizerAppUIFigure, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.PlayPauseButton = uibutton(app.C1930510EqualizerAppUIFigure, 'push');
            app.PlayPauseButton.ButtonPushedFcn = createCallbackFcn(app, @PlayPauseButtonPushed, true);
            app.PlayPauseButton.Text = 'Play/ Pause';
            app.StopButton = uibutton(app.C1930510EqualizerAppUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.EFXPanel = uipanel(app.C1930510EqualizerAppUIFigure);
            app.EFXPanel.Title = 'EFX';
            app.WahWahButton = uibutton(app.EFXPanel, 'state');
            app.WahWahButton.ValueChangedFcn = createCallbackFcn(app, @WahWahButtonValueChanged, true);
            app.WahWahButton.Text = 'Wah-Wah';
            app.MinCutoffHzKnobLabel = uilabel(app.EFXPanel);
            app.MinCutoffHzKnobLabel.Text = 'Min Cutoff (Hz)';
            app.MinCutoffHzKnob = uiknob(app.EFXPanel, 'discrete');
            app.MinCutoffHzKnob.Items = {'250', '300', '400', '500'};
            app.MinCutoffHzKnob.ValueChangedFcn = createCallbackFcn(app, @MinCutoffHzKnobValueChanged, true);
            app.MinCutoffHzKnob.Value = '500';
            app.MaxCutoffKHzKnobLabel = uilabel(app.EFXPanel);
            app.MaxCutoffKHzKnobLabel.Text = 'Max Cutoff (KHz)';
            app.MaxCutoffKHzKnob = uiknob(app.EFXPanel, 'discrete');
            app.MaxCutoffKHzKnob.Items = {'2', '3', '4', '5'};
            app.MaxCutoffKHzKnob.ValueChangedFcn = createCallbackFcn(app, @MaxCutoffKHzKnobValueChanged, true);
            app.MaxCutoffKHzKnob.Value = '3';
            app.WahFreqHzKnobLabel = uilabel(app.EFXPanel);
            app.WahFreqHzKnobLabel.Text = 'Wah Freq. (Hz)';
            app.WahFreqHzKnob = uiknob(app.EFXPanel, 'discrete');
            app.WahFreqHzKnob.Items = {'250', '500', '750', '1000', '2000', '4000', '6000'};
            app.WahFreqHzKnob.ValueChangedFcn = createCallbackFcn(app, @WahFreqHzKnobValueChanged, true);
            app.WahFreqHzKnob.Value = '2000';
            app.DampSliderLabel = uilabel(app.EFXPanel);
            app.DampSliderLabel.Text = 'Damp';
            app.DampSlider = uislider(app.EFXPanel);
            app.DampSlider.Limits = [0.02 0.4];
            app.DampSlider.MajorTicks = [0.02 0.05 0.1 0.15 0.2 0.3 0.4];
            app.DampSlider.ValueChangedFcn = createCallbackFcn(app, @DampSliderValueChanged, true);
            app.DampSlider.MinorTicks = [];
            app.DampSlider.Value = 0.02;
            app.SavePresetButton = uibutton(app.C1930510EqualizerAppUIFigure, 'push');
            app.SavePresetButton.ButtonPushedFcn = createCallbackFcn(app, @SavePresetButtonPushed, true);
            app.SavePresetButton.Text = 'Save Preset';
            app.EditField_2 = uieditfield(app.C1930510EqualizerAppUIFigure, 'text');
            app.EditField_2.Editable = 'off';
            app.FilterPresetFilePathLabel = uilabel(app.C1930510EqualizerAppUIFigure);
            app.FilterPresetFilePathLabel.Text = 'Filter Preset File Path: ';
            app.SelectPresetButton = uibutton(app.C1930510EqualizerAppUIFigure, 'push');
            app.SelectPresetButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPresetButtonPushed, true);
            app.SelectPresetButton.Text = 'Select Preset';
            app.PlayOriginalButton = uibutton(app.C1930510EqualizerAppUIFigure, 'push');
            app.PlayOriginalButton.ButtonPushedFcn = createCallbackFcn(app, @PlayOriginalButtonPushed, true);
            app.PlayOriginalButton.Text = 'Play Original';
            app.ResetSlidersButton = uibutton(app.C1930510EqualizerAppUIFigure, 'push');
            app.ResetSlidersButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSlidersButtonPushed, true);
            app.ResetSlidersButton.Text = 'Reset Sliders';
            app.SaveAudioButton = uibutton(app.C1930510EqualizerAppUIFigure, 'push');
            app.SaveAudioButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAudioButtonPushed, true);
            app.SaveAudioButton.Text = 'Save Audio';
            % Show the figure after all components are created
            app.C1930510EqualizerAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = equaliser_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.C1930510EqualizerAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.C1930510EqualizerAppUIFigure)
        end
    end
end